classdef matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        todas             matlab.ui.control.Button
        HigrometroButton  matlab.ui.control.Button
        LM35Button        matlab.ui.control.Button
        parada            matlab.ui.control.Button
        inicio            matlab.ui.control.Button
        higro             matlab.ui.control.UIAxes
        ultra             matlab.ui.control.UIAxes
        grafica           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        com % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.com= serialport('COM4',9600,'Timeout',5);

        end

        % Button pushed function: inicio
        function inicioButtonPushed(app, event)
            global parar datos voltaje constante
            fprintf(app.com,'a');
            parar=0;
            muestras=1050;
            voltaje=0;
            contador=1;
            while contador<=muestras
                if parar==1
                    break;
                else
                  valor_adc=str2double(readline(app.com));
                  constante(contador)=5;
                  voltaje(contador)=valor_adc(1)*360/4095;
                  plot(app.grafica,voltaje);
                  app.grafica.XLim=[0 contador+20];
                  app.grafica.YLim=[0 370];

                  contador=contador+1
                end
            end
        end

        % Button pushed function: parada
        function paradaButtonPushed(app, event)
            global parar
            parar=1;
        end

        % Button pushed function: LM35Button
        function LM35ButtonPushed(app, event)
            global parar datos corriente constante
            fprintf(app.com,'b');
            parar=0;
            muestras=1050;
            corriente=0;
            contador=1;
            while contador<=muestras
                if parar==1
                    break;
                else
                  valor_adc=str2double(readline(app.com));
                  constante(contador)=5;
                  corriente(contador)=(valor_adc(1)*1000)/4095;
                  plot(app.ultra,corriente);
                  app.ultra.XLim=[0 contador+20];
                  app.ultra.YLim=[0 120];
               
                  contador=contador+1;
                end
            end
        end

        % Button pushed function: HigrometroButton
        function HigrometroButtonPushed(app, event)
            global parar datos resistencia constante
            fprintf(app.com,'c');
            parar=0;
            muestras=1050;
            resistencia=0;
            contador=1;
            while contador<=muestras
                if parar==1
                    break;
                else
                  valor_adc=str2double(readline(app.com));
                  constante(contador)=5;
                  resistencia(contador)=valor_adc(1)*100/4095;
                  plot(app.higro,resistencia);
                  app.higro.XLim=[0 contador+20];
                  app.higro.YLim=[0 120];

                  contador=contador+1;
                end
            end
        end

        % Button pushed function: todas
        function todasButtonPushed(app, event)
            global parar datos res vol cor constante 
            fprintf(app.com,'c');
            parar=0;
            muestras=400;
            res=0;
            cor=0;
            vol=0;
            total=zeros(zeros,3);
            contador=1;
            while contador<=muestras
                if parar==1
                      break;
                else
                    d=read(app.com)
                    
%                     for i=1:1
%                         for j=1:3
%                             c=strsplit(d,':')
%                             h=str2double(c)
%                             f(i,j)=h(1,j);
%                         end
%                     end
%                   s1=transpose(f(:,1));
%                   s2=transpose(f(:,2));
%                   s3=transpose(f(:,3));
%                   plot(app.ultra,s1);
%                   app.ultra.XLim=[0 contador+20];
%                   app.ultra.YLim=[0 120];
                  contador=contador+1
                  disp(d)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafica = uiaxes(app.UIFigure);
            title(app.grafica, 'Potenciometro')
            xlabel(app.grafica, 'muestras')
            ylabel(app.grafica, 'grados')
            zlabel(app.grafica, 'Z')
            app.ultra = uiaxes(app.UIFigure);
            title(app.ultra, 'LM35')
            xlabel(app.ultra, 'muestras')
            ylabel(app.ultra, 'Temperatura (°c)')
            zlabel(app.ultra, 'Z')
            app.higro = uiaxes(app.UIFigure);
            title(app.higro, 'higrometro')
            xlabel(app.higro, 'muestras')
            ylabel(app.higro, 'humedad')
            zlabel(app.higro, 'Z')
            app.inicio = uibutton(app.UIFigure, 'push');
            app.inicio.ButtonPushedFcn = createCallbackFcn(app, @inicioButtonPushed, true);
            app.inicio.Text = 'Potenciometro';
            app.parada = uibutton(app.UIFigure, 'push');
            app.parada.ButtonPushedFcn = createCallbackFcn(app, @paradaButtonPushed, true);
            app.parada.Text = 'Parada';
            app.LM35Button = uibutton(app.UIFigure, 'push');
            app.LM35Button.ButtonPushedFcn = createCallbackFcn(app, @LM35ButtonPushed, true);
            app.LM35Button.Text = 'LM35';
            app.HigrometroButton = uibutton(app.UIFigure, 'push');
            app.HigrometroButton.ButtonPushedFcn = createCallbackFcn(app, @HigrometroButtonPushed, true);
            app.HigrometroButton.Text = 'Higrometro';
            app.todas = uibutton(app.UIFigure, 'push');
            app.todas.ButtonPushedFcn = createCallbackFcn(app, @todasButtonPushed, true);
            app.todas.Text = 'Simultaneo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end